classdef entregable_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InfectadaCheckBox          matlab.ui.control.CheckBox
        TipodeClulaLabel           matlab.ui.control.Label
        NormalCheckBox_2           matlab.ui.control.CheckBox
        CargasButtonGroup          matlab.ui.container.ButtonGroup
        q6                         matlab.ui.control.RadioButton
        q4                         matlab.ui.control.RadioButton
        q2                         matlab.ui.control.RadioButton
        Slider                     matlab.ui.control.Slider
        TamaoSpinner               matlab.ui.control.Spinner
        TamaoSpinnerLabel          matlab.ui.control.Label
        DistanciaentreplacasLabel  matlab.ui.control.Label
        IncrementoSlider           matlab.ui.control.Slider
        IncrementoSliderLabel      matlab.ui.control.Label
        SimularButton              matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            cla(app.UIAxes, "reset");

            % Variables
%             q1 = app.QEditField.Value;
%             q2 = app.QEditField_2.Value;
% 
%             posP = [app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value];
%             posN = [app.XEditField_2.Value, app.YEditField_2.Value, app.ZEditField_2.Value];

            % Espacio
            m = app.TamaoSpinner.Value;
            dm = app.IncrementoSlider.Value;
            d = app.Slider.Value;

            %[x,y,z] = meshgrid(-m:dm:m);
 
%             % Graficación de las Cargas
%             radioP = app.RadioEditField.Value;
%             radioN = app.RadioEditField_2.Value;
% 
%             [xx, yy, zz] = sphere(20);
%             esfera1 = surf(app.UIAxes, radioP*xx + posP(1), radioP*yy + posP(2), radioP*zz + posP(3));
%             esfera1.FaceColor = 'red';
%             hold(app.UIAxes, 'on');
%             
%             esfera2 = surf(app.UIAxes, radioN*xx + posN(1), radioN*yy + posN(2), radioN*zz + posN(3));
%             esfera2.FaceColor = 'blue';
%             
%             axis equal;
%             %axis(app.UIAxes, 'equal');

            % Cargas
            q = [0, -1.5+d, 0, 0; 
                0, 1.5-d, 0, 0];
            if app.q2.Value == 1
                q = [3, -m+1.5+d, 0, 0; 
                    -3, m-1.5-d, 0, 0];
            elseif app.q4.Value == 1
                q = [3, -m+1.5+d, m-1, 0; 
                    -3, m-1.5-d, m-1.5, 0;
                     3, -m+1.5+d, 1-m, 0;
                    -3, m-1.5-d, 1.5-m, 0];
            elseif app.q6.Value == 1
                q = [3, -m+1.5+d, 0, 0; 
                    -3, m-1.5-d, 0, 0;
                     3, -m+1.5+d, m-1, 0;
                    -3, m-1.5-d, m-1.5, 0;
                     3, -m+1.5+d, 1-m, 0;
                    -3, m-1.5-d, 1.5-m, 0];
            end


            [Ex, Ey, Ez, x, y, z] = campoElectrico(q, m, dm);

            % Direcciones
            E = sqrt(Ex.^2 + Ey.^2 + Ez.^2);
            Ex = Ex./E;
            Ey = Ey./E;
            Ez = Ez./E;

            % Graficación del campo
            quiver3(app.UIAxes,x,y,z,Ex,Ey,Ez);
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');


            % Dibujo de las placas
            % Placa positiva
            x = [-m+2+d -m+1+d -m+1+d -m+2+d -m+2+d -m+1+d -m+1+d -m+2+d];
            y = [-m -m m m -m -m m m];
            z = [-m+1 -m+1 -m+1 -m+1 m-1 m-1 m-1 m-1];
            f = [1 2 3 4; 5 6 7 8; 1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8];
            patch(app.UIAxes, 'XData',x,'YData',y,'ZData',z,'Faces',f,'FaceColor','red')
            
            % Placa negativa
            x = [m-2-d m-1-d m-1-d m-2-d m-2-d m-1-d m-1-d m-2-d];
            y = [-m+1 -m+1 m-1 m-1 -m+1 -m+1 m-1 m-1];
            z = [-m+1 -m+1 -m+1 -m+1 m-1 m-1 m-1 m-1];
            f = [1 2 3 4; 5 6 7 8; 1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8];
            patch(app.UIAxes,'XData',x,'YData',y,'ZData',z,'Faces',f,'FaceColor','blue')
            view(3)

            % Células
            y = -m:0.1:m;
            z = zeros(size(y));
            radio = 0.3;
            [xx, yy, zz] = sphere(20);

 
            if app.NormalCheckBox_2.Value == 1
                x = 0:-0.1:-0.75-d;
                x = [x ones(1,numel(y) - numel(x)).* -0.75-d];
                esfera1 = surf(app.UIAxes, radio*xx + x(1), radio*yy + y(1), radio*zz + z(1));
                esfera1.FaceColor = 'green';
                dibujaCell(x,y,z,radio,xx,yy,zz,esfera1)

            end

            if app.InfectadaCheckBox.Value == 1
                x = 0:0.1:0.75+d;
                x = [x ones(1,numel(y) - numel(x)).* 0.75+d];
                esfera2 = surf(app.UIAxes, radio*xx + x(1), radio*yy + y(1), radio*zz + z(1));
                esfera2.FaceColor = 'white';
                dibujaCell(x,y,z,radio,xx,yy,zz,esfera2)
            end
            
            function dibujaCell(x,y,z,radio,xx,yy,zz,esfera)
                for num = 1:numel(y)
                    % Actualizar las coordenadas de la esfera
                
                    set(esfera, 'XData', radio*xx + x(num), 'YData', radio*yy + y(num), 'ZData', radio*zz + z(num));
                    drawnow; % Actualizar la pantalla
                end
            end

            % Función para crear cargas
            function [Ex, Ey, Ez, x, y, z] = campoElectrico(cargas, m, dm)
                % Cargas es una matriz Nx4 donde cada fila es [carga, posX, posY, posZ]
                % m es el tamaño del espacio
                % dm es la resolución entre puntos
                
                [x,y,z] = meshgrid(-m:dm:m);
                
                numCargas = size(cargas, 1);
                Ex = zeros(size(x));
                Ey = zeros(size(y));
                Ez = zeros(size(z));
            
                for i = 1:numCargas
                    q = cargas(i, 1);
                    pos = cargas(i, 2:4);
                    r = sqrt((x-pos(1)).^2 + (y-pos(2)).^2);
                    Ex = Ex + q*(x-pos(1))./r.^3;
                    Ey = Ey + q*(y-pos(2))./r.^3;
                    Ez = Ez + q*(z-pos(3))./r.^3;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Campo Eléctrico entre cargas')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-3 3];
            app.UIAxes.YLim = [-3 3];
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.IncrementoSliderLabel = uilabel(app.UIFigure);
            app.IncrementoSliderLabel.Text = 'Incremento';
            app.IncrementoSlider = uislider(app.UIFigure);
            app.IncrementoSlider.Limits = [0.1 1];
            app.IncrementoSlider.Value = 0.4;
            app.DistanciaentreplacasLabel = uilabel(app.UIFigure);
            app.DistanciaentreplacasLabel.Text = 'Distancia entre placas';
            app.TamaoSpinnerLabel = uilabel(app.UIFigure);
            app.TamaoSpinnerLabel.Text = 'Tamaño';
            app.TamaoSpinner = uispinner(app.UIFigure);
            app.TamaoSpinner.Limits = [0.1 10];
            app.TamaoSpinner.Value = 3;
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-1 1];
            app.CargasButtonGroup = uibuttongroup(app.UIFigure);
            app.CargasButtonGroup.Title = 'Cargas';
            app.q2 = uiradiobutton(app.CargasButtonGroup);
            app.q2.Text = '2';
            app.q2.Value = true;
            app.q4 = uiradiobutton(app.CargasButtonGroup);
            app.q4.Text = '4';
            app.q6 = uiradiobutton(app.CargasButtonGroup);
            app.q6.Text = '6';
            app.NormalCheckBox_2 = uicheckbox(app.UIFigure);
            app.NormalCheckBox_2.Text = 'Normal';
            app.TipodeClulaLabel = uilabel(app.UIFigure);
            app.TipodeClulaLabel.Text = 'Tipo de Célula';
            app.InfectadaCheckBox = uicheckbox(app.UIFigure);
            app.InfectadaCheckBox.Text = 'Infectada';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = entregable_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end