classdef mi_primera_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ZsinRRLabel               matlab.ui.control.Label
        Rsqrtx2y2epsLabel         matlab.ui.control.Label
        TipodeGrficaButtonGroup   matlab.ui.container.ButtonGroup
        MeshButton                matlab.ui.control.RadioButton
        SurfButton                matlab.ui.control.RadioButton
        Plot3Button_2             matlab.ui.control.RadioButton
        DCheckBox                 matlab.ui.control.CheckBox
        ColordelFondoButtonGroup  matlab.ui.container.ButtonGroup
        AzulButton                matlab.ui.control.RadioButton
        VerdeButton               matlab.ui.control.RadioButton
        RojoButton                matlab.ui.control.RadioButton
        Button1                   matlab.ui.control.Button
        Increment                 matlab.ui.control.Slider
        PasoLabel                 matlab.ui.control.Label
        Space                     matlab.ui.control.NumericEditField
        EspacioLabel              matlab.ui.control.Label
        Graph                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Space
        function SpaceValueChanged(app, event)
            value = app.Space.Value;
            
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            % cla(objeto, acción) -> limpia lo que le mandes
            cla(app.Graph, "reset");
            valor_espacio = app.Space.Value;
            valor_paso = app.Increment.Value;

            [x,y] = meshgrid(-valor_espacio:valor_paso:valor_espacio);

            plot(app.Graph, x, y, ".b")
        end

        % Value changed function: Increment
        function IncrementValueChanged(app, event)
            value = app.Increment.Value;
            
        end

        % Selection changed function: ColordelFondoButtonGroup
        function ColordelFondoButtonGroupSelectionChanged(app, event)
            global op1 op2 op3
            selectedButton = app.ColordelFondoButtonGroup.SelectedObject;
            
            op1 = app.RojoButton.Value;
            op2 = app.VerdeButton.Value;
            op3 = app.AzulButton.Value;
            if op1 == 1
                app.UIFigure.Color = "#ff0000";
            elseif op2 == 1
                    app.UIFigure.Color = "#00ff00";
            elseif op3 == 1
                app.UIFigure.Color  = "#0000ff"
            end
            %op1 = app.Graph.GridColor.Value;


        end

        % Value changed function: DCheckBox
        function DCheckBoxValueChanged(app, event)
            value = app.DCheckBox.Value;
            
            cla(app.Graph, "reset");
            m = app.Space.Value;
            dm = app.Increment.Value;
            x = -m:dm:m;
            y = -m:dm:m;
            z = -m:dm:m;

            [xx,yy,zz] = meshgrid(x,y,z);
            Ex = ones(size(xx)).*0;
            Ey = ones(size(yy)).*0;
            Ez = ones(size(zz));
            quiver3(app.Graph,xx,yy,zz,Ex,Ey,Ez);
            %plot3(app.Graph, x, y, z, ".b")
        end

        % Selection changed function: TipodeGrficaButtonGroup
        function TipodeGrficaButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipodeGrficaButtonGroup.SelectedObject;
            op1 = app.Plot3Button_2.Value;
            op2 = app.SurfButton.Value;
            op3 = app.Meshgrid.Value;
            cla(app.Graph, "reset");
            m = app.Space.Value;
            dm = app.Increment.Value;
            x = -m:dm:m;
            y = -m:dm:m;
            z = -m:dm:m;

            if op1 == 1
                
    
            elseif op2 == 1


            elseif op3 == 1
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph = uiaxes(app.UIFigure);
            title(app.Graph, 'Gráfico de Función')
            xlabel(app.Graph, 'X')
            ylabel(app.Graph, 'Y')
            zlabel(app.Graph, 'Z')
            app.EspacioLabel = uilabel(app.UIFigure);
            app.EspacioLabel.Text = 'Espacio';
            app.Space = uieditfield(app.UIFigure, 'numeric');
            app.Space.Limits = [2 8];
            app.Space.ValueChangedFcn = createCallbackFcn(app, @SpaceValueChanged, true);
            app.Space.Value = 2;
            app.PasoLabel = uilabel(app.UIFigure);
            app.PasoLabel.Text = 'Paso';
            app.Increment = uislider(app.UIFigure);
            app.Increment.Limits = [0.1 1];
            app.Increment.ValueChangedFcn = createCallbackFcn(app, @IncrementValueChanged, true);
            app.Increment.Value = 0.5;
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = 'Simular';
            app.ColordelFondoButtonGroup = uibuttongroup(app.UIFigure);
            app.ColordelFondoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColordelFondoButtonGroupSelectionChanged, true);
            app.ColordelFondoButtonGroup.Title = 'Color del Fondo';
            app.RojoButton = uiradiobutton(app.ColordelFondoButtonGroup);
            app.RojoButton.Text = 'Rojo';
            app.RojoButton.Value = true;
            app.VerdeButton = uiradiobutton(app.ColordelFondoButtonGroup);
            app.VerdeButton.Text = 'Verde';
            app.AzulButton = uiradiobutton(app.ColordelFondoButtonGroup);
            app.AzulButton.Text = 'Azul';
            app.DCheckBox = uicheckbox(app.UIFigure);
            app.DCheckBox.ValueChangedFcn = createCallbackFcn(app, @DCheckBoxValueChanged, true);
            app.DCheckBox.Text = '3D';
            app.TipodeGrficaButtonGroup = uibuttongroup(app.UIFigure);
            app.TipodeGrficaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodeGrficaButtonGroupSelectionChanged, true);
            app.TipodeGrficaButtonGroup.Title = 'Tipo de Gráfica';
            app.Plot3Button_2 = uiradiobutton(app.TipodeGrficaButtonGroup);
            app.Plot3Button_2.Text = 'Plot3';
            app.Plot3Button_2.Value = true;
            app.SurfButton = uiradiobutton(app.TipodeGrficaButtonGroup);
            app.SurfButton.Text = 'Surf';
            app.MeshButton = uiradiobutton(app.TipodeGrficaButtonGroup);
            app.MeshButton.Text = 'Mesh';
            app.Rsqrtx2y2epsLabel = uilabel(app.UIFigure);
            app.Rsqrtx2y2epsLabel.Text = 'R = sqrt(x.^2 + y.^2) + eps';
            app.ZsinRRLabel = uilabel(app.UIFigure);
            app.ZsinRRLabel.Text = 'Z = sin(R)./R';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mi_primera_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end